classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ResimekleButton  matlab.ui.control.Button
        Label            matlab.ui.control.Label
        YSAButton        matlab.ui.control.Button
        SVMButton        matlab.ui.control.Button
        KNNButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        ysa; % Description
        knn;
        svm;
        matdeg=0;
        YSA;
        KNN;
        SVM;
        no;
    end
    
    methods (Access = public)
        
        function testYsaFunk(app,YSA)
            
            for i=1:5
                donusYsa=YSA(i,end)
                donusYsa=int8(donusYsa);
               
                if donusYsa == 1
                    app.matdeg=i; 
                end
            end
            
                 
            if app.matdeg==1
                app.Label.Text="Elma";
            elseif app.matdeg==2
                app.Label.Text="Muz";
            elseif app.matdeg==3
                app.Label.Text="Kiraz";
            elseif app.matdeg==4
                app.Label.Text="Mango";
            elseif app.matdeg==5
                app.Label.Text="Portakal";
            else
                app.no=0;
            end
            
        end
        
        
        function tesSvmaFunk(app,SVM)
            if SVM == 1
                app.Label.Text="Elma";
            elseif SVM==2
                app.Label.Text="Muz";
            elseif SVM==3
                app.Label.Text="Vişne";
            elseif SVM==4
                app.Label.Text="Mango";
            elseif SVM==5
                app.Label.Text="portakal";
            else
            app.no=0;
            end
            
        end
        
        function testKnnFunk(app,KNN)
            if KNN == 1
                app.Label.Text="Elma";
            elseif KNN==2
                app.Label.Text="Muz";
            elseif KNN==3
                app.Label.Text="Vişne";
            elseif KNN==4
                app.Label.Text="Mango";
            elseif KNN==5
                app.Label.Text="portakal";
            else
            app.no=0;
            end
        end
        
        function kontrolFunk(app)
            if app.knn~=1
                app.knn=0;
            elseif app.svm ~=1
                app.svm=0;
            elseif app.ysa~=1
                app.ysa=0;
            else
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function YSACheckBoxValueChanged(app, event)

            
        end

        % Button pushed function: ResimekleButton
        function ResimekleButtonPushed(app, event)
 %           kontrolFunk(app);
            
            [app.YSA,app.KNN,app.SVM]=ysa_test_funk(app.ysa,app.knn,app.svm);
            
            if app.YSA~=0
                 testYsaFunk(app,app.YSA);
            end
            if app.KNN~=0
                testKnnFunk(app,app.KNN)
            end
            if app.SVM~=0
                tesSvmaFunk(app,app.SVM)
            end


        end

        % Callback function
        function KNNCheckBoxValueChanged(app, event)

        end

        % Callback function
        function SVMCheckBoxValueChanged(app, event)
        
        end

        % Button pushed function: YSAButton
        function YSAButtonPushed(app, event)
            app.ysa=1;
        end

        % Button pushed function: SVMButton
        function SVMButtonPushed(app, event)
            app.svm=1;
        end

        % Button pushed function: KNNButton
        function KNNButtonPushed(app, event)
            app.knn=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ResimekleButton = uibutton(app.UIFigure, 'push');
            app.ResimekleButton.ButtonPushedFcn = createCallbackFcn(app, @ResimekleButtonPushed, true);
            app.ResimekleButton.Text = 'Resim ekle';
            app.Label = uilabel(app.UIFigure);
            app.YSAButton = uibutton(app.UIFigure, 'push');
            app.YSAButton.ButtonPushedFcn = createCallbackFcn(app, @YSAButtonPushed, true);
            app.YSAButton.Text = 'YSA';
            app.SVMButton = uibutton(app.UIFigure, 'push');
            app.SVMButton.ButtonPushedFcn = createCallbackFcn(app, @SVMButtonPushed, true);
            app.SVMButton.Text = 'SVM';
            app.KNNButton = uibutton(app.UIFigure, 'push');
            app.KNNButton.ButtonPushedFcn = createCallbackFcn(app, @KNNButtonPushed, true);
            app.KNNButton.Text = 'KNN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end